classdef GyroboyControlApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GyroboyControlUIFigure      matlab.ui.Figure
        VELLabel                    matlab.ui.control.Label
        VEL                         matlab.ui.control.Slider
        TURNLabel                   matlab.ui.control.Label
        TURN                        matlab.ui.control.Slider
        STOP                        matlab.ui.control.Button
        IPEditFieldLabel            matlab.ui.control.Label
        IP                          matlab.ui.control.EditField
        Button                      matlab.ui.control.Button
        UseJoystickButton           matlab.ui.control.StateButton
        TestedwithXBoxgamepadLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        u % UDP Connection
        t % joystick timer
        joy % joystick
        joyTs = 0.1
    end
    
    methods (Access = private)
        
        function sendData(app, vel, turn)
            fwrite(app.u, [vel turn], 'int8');
        end
        
        function readJoystick(app)
            axes = read(app.joy);
            vel = -axes(2) * 5;
            turn = axes(1) * 5;
            app.sendData(vel, turn);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('img');
            app.u = udp(app.IP.Value, 25000);
            fopen(app.u);
        end

        % Close request function: GyroboyControlUIFigure
        function GyroboyControlUIFigureCloseRequest(app, event)
            fclose(app.u);
            if class(app.t) == "timer"
                stop(app.t);
                delete(app.t);
            end
            delete(app)
        end

        % Value changed function: TURN, VEL
        function ValueChanged(app, event)
            vel = app.VEL.Value;
            turn = app.TURN.Value;
            app.sendData(vel, turn);
        end

        % Button pushed function: STOP
        function STOPButtonPushed(app, event)
            app.VEL.Value = 0;
            app.TURN.Value = 0;
            app.ValueChanged(event);
        end

        % Value changed function: UseJoystickButton
        function UseJoystickButtonValueChanged(app, event)
            value = app.UseJoystickButton.Value;
            % set up timer
            if value
                app.joy = vrjoystick(1);
                app.t = timer;
                app.t.TimerFcn = @(~, ~)app.readJoystick;
                app.t.ExecutionMode = 'fixedRate';
                app.t.Period = app.joyTs;
                app.t.BusyMode = 'drop';
                start(app.t);
            else
                if class(app.t) == "timer"
                    stop(app.t);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GyroboyControlUIFigure = uifigure('Visible', 'off');
            app.GyroboyControlUIFigure.Name = 'Gyroboy Control';
            app.GyroboyControlUIFigure.CloseRequestFcn = createCallbackFcn(app, @GyroboyControlUIFigureCloseRequest, true);
            app.VELLabel = uilabel(app.GyroboyControlUIFigure);
            app.VELLabel.Text = 'VEL';
            app.VEL = uislider(app.GyroboyControlUIFigure);
            app.VEL.Limits = [-10 10];
            app.VEL.MajorTicks = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.VEL.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.TURNLabel = uilabel(app.GyroboyControlUIFigure);
            app.TURNLabel.Text = 'TURN';
            app.TURN = uislider(app.GyroboyControlUIFigure);
            app.TURN.Limits = [-10 10];
            app.TURN.MajorTicks = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.TURN.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.STOP = uibutton(app.GyroboyControlUIFigure, 'push');
            app.STOP.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOP.Text = 'STOP';
            app.IPEditFieldLabel = uilabel(app.GyroboyControlUIFigure);
            app.IPEditFieldLabel.Text = 'IP';
            app.IP = uieditfield(app.GyroboyControlUIFigure, 'text');
            app.IP.Value = '192.168.1.34';
            app.Button = uibutton(app.GyroboyControlUIFigure, 'push');
            app.Button.Icon = 'gyroboy-photo.png';
            app.Button.Text = '';
            app.UseJoystickButton = uibutton(app.GyroboyControlUIFigure, 'state');
            app.UseJoystickButton.ValueChangedFcn = createCallbackFcn(app, @UseJoystickButtonValueChanged, true);
            app.UseJoystickButton.Text = 'Use Joystick';
            app.TestedwithXBoxgamepadLabel = uilabel(app.GyroboyControlUIFigure);
            app.TestedwithXBoxgamepadLabel.Text = 'Tested with XBox gamepad';
            % Show the figure after all components are created
            app.GyroboyControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GyroboyControlApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GyroboyControlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GyroboyControlUIFigure)
        end
    end
end